classdef szczegoly < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        UITable                  matlab.ui.control.Table
        SzczegoloweDanePanel     matlab.ui.container.Panel
        ZawartoscPlikuLabel      matlab.ui.control.Label
        ZapiszDane               matlab.ui.control.Button
        EdytujDame               matlab.ui.control.Button
        ZawartoscPlikuTextArea   matlab.ui.control.TextArea
        ZaimportujDaneButton     matlab.ui.control.Button
        NazwaPlikuEditField      matlab.ui.control.EditField
        NazwaplikuLabel          matlab.ui.control.Label
        ParametryszklarniPanel   matlab.ui.container.Panel
        WczytajSzklarnieButton   matlab.ui.control.Button
        ZapiszSzklarnieButton    matlab.ui.control.Button
        EdytujSzklarnieButton    matlab.ui.control.Button
        WysokoscJednostkaLabel   matlab.ui.control.Label
        SzerokoscJednostkaLabel  matlab.ui.control.Label
        DlugoscJednostkaLabel    matlab.ui.control.Label
        WysokoscEditField        matlab.ui.control.NumericEditField
        WysokoEditFieldLabel     matlab.ui.control.Label
        SzerokoscEditField       matlab.ui.control.NumericEditField
        SzerokomLabel            matlab.ui.control.Label
        DlugoscEditField         matlab.ui.control.NumericEditField
        DugomLabel               matlab.ui.control.Label
        NazwaEditField           matlab.ui.control.EditField
        NazwaEditFieldLabel      matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'Szczegóły';    
            % Odczytanie i załadowanie danych z plików na starcie
            if (exist('szklarnia.txt','file') == 2)
            fileID = fopen('szklarnia.txt');
            dane = fscanf(fileID, '%s');
            params = strsplit(dane,',');
            fclose(fileID);
            app.NazwaEditField.Value = string(params(1));
            app.DlugoscEditField.Value = str2double(params(2));
            app.SzerokoscEditField.Value = str2double(params(3));
            app.WysokoscEditField.Value = str2double(params(4));
            else
            app.NazwaEditField.Value = "Moja szklarnia";
            end

            if (exist('rosliny.txt','file') == 2)
            fileID = fopen('rosliny.txt');
            dane = fscanf(fileID, '%s');
            params = strsplit(dane,',');
            fclose(fileID);
            
            app.UITable.Data = {}; % wyczyszczenie tablicy przed załadowaniem danych
            app.UITable.ColumnName = {'Rodzaj', 'Nazwa', 'Pochłanianie', 'Szerokość', 'Długość', 'Wysokość', 'Data poprzedniego podlania', 'Stan'};
            app.UITable.Data = {'Pomidory', '4.3', '45', 'usycha', '1/17/2023'};
            app.UITable.Data = vertcat(app.UITable.Data, {'Tulipan', '0.3', '10', 'umiarkowane', '1/18/2023'});
            app.UITable.Data = vertcat(app.UITable.Data, {'Truskawki', '2.1', '15', 'nawodnione', '1/19/2023'});
            app.UITable.Data = vertcat(app.UITable.Data, {'Maliny', '1.8', '12', 'nawodnione', '1/19/2023'});
            app.UITable.Data = vertcat(app.UITable.Data, {'Róże', '0.5', '23', 'nawodnione', '1/20/2023'});
            end
        end

        % Button pushed function: EdytujSzklarnieButton
        function EdytujSzklarnieButtonPushed(app, event)
            app.EdytujSzklarnieButton.Enable = "off";
            app.ZapiszSzklarnieButton.Enable = "on";
            app.NazwaEditField.Editable = "on";
            app.DlugoscEditField.Editable = "on";
            app.SzerokoscEditField.Editable = "on";
            app.WysokoscEditField.Editable = "on";
        end

        % Button pushed function: ZapiszSzklarnieButton
        function ZapiszSzklarnieButtonPushed(app, event)
            app.EdytujSzklarnieButton.Enable = "on";
            app.ZapiszSzklarnieButton.Enable = "off";
            app.NazwaEditField.Editable = "off";
            app.DlugoscEditField.Editable = "off";
            app.SzerokoscEditField.Editable = "off";
            app.WysokoscEditField.Editable = "off";

            NazwaSzklarni = num2str(app.NazwaEditField.Value);
            DlugoscSzklarni = num2str(app.DlugoscEditField.Value);
            SzerokoscSzklarni = num2str(app.SzerokoscEditField.Value);
            WysokoscSzklarni = num2str(app.WysokoscEditField.Value);

            % połączenie stringów i rozdzielenie przecinkiem
            resultString = strjoin({NazwaSzklarni, DlugoscSzklarni, SzerokoscSzklarni, WysokoscSzklarni}, ','); 
            delete('szklarnia.txt'); % usuwamy plik, aby stworzyć nowy z danymi
            fid = fopen('szklarnia.txt', 'w'); % otwarcie pliku do zapisu
            fprintf(fid, '%s', resultString); % zapis do pliku
            fclose(fid); % zamknięcie pliku
        end

        % Button pushed function: WczytajSzklarnieButton
        function WczytajSzklarnieButtonPushed(app, event)
            
            % Otworzenie okienka z wyborem pliku do odczytu danych
            [file, path] = uigetfile({'*.txt'},'Wybierz plik');
            if file ~= 0
                fullPath = fullfile(path, file);
                fileID = fopen(fullPath);
            else
                fileID = fopen('szklarnia.txt');
            end
            
            % Odczytanie danych z wybranego pliku
            dane = fscanf(fileID, '%s');
            params = strsplit(dane,',');
            fclose(fileID);

            nazwa = string(params(1));
            dlugosc = str2double(params(2));
            szerokosc = str2double(params(3));
            wysokosc = str2double(params(4));

            % Wyświetlenie 4 parametrów w 4 EditField'ach
            app.NazwaEditField.Value = nazwa;
            app.DlugoscEditField.Value = dlugosc;
            app.SzerokoscEditField.Value = szerokosc;
            app.WysokoscEditField.Value = wysokosc;
            
        end

        % Button pushed function: ZaimportujDaneButton
        function ZaimportujDaneButtonPushed(app, event)
            
            % Odblokowanie widoku do wyświetlenia danych
            app.NazwaPlikuEditField.Enable = "on";
            app.ZawartoscPlikuTextArea.Enable = "on";
            app.ZawartoscPlikuLabel.Enable = "on";

            % Otworzenie okienka z wyborem pliku do odczytu danych
            [file, path] = uigetfile({'*.txt'},'Wybierz plik');
            if file ~= 0
                fullPath = fullfile(path, file);
                fileID = fopen(fullPath, 'r');
            else
                fileID = fopen('rosliny.txt');
            end
            
            % Odczytanie danych z wybranego pliku
            file_contents = fileread(file);
            linie = strsplit(file_contents, '\n');
            app.ZawartoscPlikuTextArea.Value = strjoin(linie, '\n');
            fclose(fileID);
            % Otwórz plik tekstowy

            % Wczytaj zawartość pliku tekstowego
            %text = fread(fid,inf,'*char')';

            % Zastąp każdy znak nowej linii na akapit
            %text = strrep(text,newline,sprintf('\n\n'));

            % Zamknij plik tekstowy
            %fclose(fid);

            % Ustaw tekst w TextArea
            %app.ZawartoscPlikuTextArea.Value = text;

            % Wyświetlenie wszystkich danych w Tablicy
            app.UITable.ColumnName = {'Rodzaj', 'Nazwa', 'Pochłanianie', 'Szerokość', 'Długość', 'Wysokość', 'Data poprzedniego podlania', 'Stan'};
            
            wiersz = strjoin(app.ZawartoscPlikuTextArea.Value, '\n');
            dane = strsplit(wiersz, ',');
            app.UITable.Data = {}; % wyczyszczenie tablicy przed załadowaniem danych
            %app.ZawartoscPlikuTextArea.Value = string(dane(1));
            app.ZawartoscPlikuTextArea.Value = string(dane(1));
            %roslina = {string(dane(1)), string(dane(2)), string(dane(3)), string(dane(4)), string(dane(5)), string(dane(6)), string(dane(7)), string(dane(8))};
            roslina = {'kwiat','Tulipany','0.1','10','20','30','01/02/2023','usycha'};
            app.UITable.Data = [app.UITable.Data; roslina];
            app.UITable.Data = [app.UITable.Data; {'owoc','Truskawki','0.5','20','40','15','31/01/2023','umiarkowane'}];
            app.UITable.Data = [app.UITable.Data; {'warzywo','Marchewka','0.3','10','10','10','30/01/2023','podlane'}];
            %for i=1:1:10
            %app.UITable.Data = vertcat(app.UITable.Data, {dane(1), dane(2), dane(3), dane(4), dane(5), dane(6), dane(7), dane(8)});
            %app.UITable.Data = vertcat(app.UITable.Data, {'Tulipan', '0.3', '10', 'umiarkowane', '1/18/2023'});
            %end
            %app.UITable.Data = vertcat(app.UITable.Data, {'Tulipan', '0.3', '10', 'umiarkowane', '1/18/2023'});


            % Wyświetlanie nazwy pliku w EditField
            app.NazwaPlikuEditField.Value = file;

        end

        % Button pushed function: EdytujDame
        function EdytujDamePushed(app, event)
            app.EdytujDame.Enable = "off";
            app.ZapiszDane.Enable = "on";
            app.ZawartoscPlikuTextArea.Editable = "on";
            app.ZawartoscPlikuTextArea.Enable = "on";
            app.ZawartoscPlikuLabel.Enable = "on";
        end

        % Button pushed function: ZapiszDane
        function ZapiszDanePushed(app, event)
            app.EdytujDame.Enable = "on";
            app.ZapiszDane.Enable = "off";
            app.ZawartoscPlikuTextArea.Editable = "off";
            app.NazwaPlikuEditField.Enable = "off";
            app.ZawartoscPlikuLabel.Enable = "off";

            % Odczytanie danych z wybranego pliku
            dane = app.ZawartoscPlikuTextArea.Value;

            % połączenie stringów i rozdzielenie przecinkiem 
            delete('rosliny.txt'); % usuwamy plik, aby stworzyć nowy z danymi
            fid = fopen('rosliny.txt', 'w'); % otwarcie pliku do zapisu
            fprintf(fid, '%s\n', dane); % zapis do pliku
            fclose(fid); % zamknięcie pliku
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ParametryszklarniPanel = uipanel(app.UIFigure);
            app.ParametryszklarniPanel.Title = 'Parametry szklarni';
            app.NazwaEditFieldLabel = uilabel(app.ParametryszklarniPanel);
            app.NazwaEditFieldLabel.Text = 'Nazwa';
            app.NazwaEditField = uieditfield(app.ParametryszklarniPanel, 'text');
            app.NazwaEditField.Editable = 'off';
            app.DugomLabel = uilabel(app.ParametryszklarniPanel);
            app.DugomLabel.Text = 'Długość: ';
            app.DlugoscEditField = uieditfield(app.ParametryszklarniPanel, 'numeric');
            app.DlugoscEditField.Editable = 'off';
            app.SzerokomLabel = uilabel(app.ParametryszklarniPanel);
            app.SzerokomLabel.Text = 'Szerokość: ';
            app.SzerokoscEditField = uieditfield(app.ParametryszklarniPanel, 'numeric');
            app.SzerokoscEditField.Editable = 'off';
            app.WysokoEditFieldLabel = uilabel(app.ParametryszklarniPanel);
            app.WysokoEditFieldLabel.Text = 'Wysokość: ';
            app.WysokoscEditField = uieditfield(app.ParametryszklarniPanel, 'numeric');
            app.WysokoscEditField.Editable = 'off';
            app.DlugoscJednostkaLabel = uilabel(app.ParametryszklarniPanel);
            app.DlugoscJednostkaLabel.Text = 'm';
            app.SzerokoscJednostkaLabel = uilabel(app.ParametryszklarniPanel);
            app.SzerokoscJednostkaLabel.Text = 'm';
            app.WysokoscJednostkaLabel = uilabel(app.ParametryszklarniPanel);
            app.WysokoscJednostkaLabel.Text = 'm';
            app.EdytujSzklarnieButton = uibutton(app.ParametryszklarniPanel, 'push');
            app.EdytujSzklarnieButton.ButtonPushedFcn = createCallbackFcn(app, @EdytujSzklarnieButtonPushed, true);
            app.EdytujSzklarnieButton.Text = 'Edytuj';
            app.ZapiszSzklarnieButton = uibutton(app.ParametryszklarniPanel, 'push');
            app.ZapiszSzklarnieButton.ButtonPushedFcn = createCallbackFcn(app, @ZapiszSzklarnieButtonPushed, true);
            app.ZapiszSzklarnieButton.Enable = 'off';
            app.ZapiszSzklarnieButton.Text = 'Zapisz';
            app.WczytajSzklarnieButton = uibutton(app.ParametryszklarniPanel, 'push');
            app.WczytajSzklarnieButton.ButtonPushedFcn = createCallbackFcn(app, @WczytajSzklarnieButtonPushed, true);
            app.WczytajSzklarnieButton.Text = 'Wczytaj';
            app.SzczegoloweDanePanel = uipanel(app.UIFigure);
            app.SzczegoloweDanePanel.Title = 'Szczegółowe dane';
            app.NazwaplikuLabel = uilabel(app.SzczegoloweDanePanel);
            app.NazwaplikuLabel.Text = 'Nazwa pliku:';
            app.NazwaPlikuEditField = uieditfield(app.SzczegoloweDanePanel, 'text');
            app.NazwaPlikuEditField.Editable = 'off';
            app.NazwaPlikuEditField.Enable = 'off';
            app.NazwaPlikuEditField.Placeholder = 'przykladowe_dane.txt';
            app.ZaimportujDaneButton = uibutton(app.SzczegoloweDanePanel, 'push');
            app.ZaimportujDaneButton.ButtonPushedFcn = createCallbackFcn(app, @ZaimportujDaneButtonPushed, true);
            app.ZaimportujDaneButton.Text = 'Zaimportuj dane';
            app.ZawartoscPlikuTextArea = uitextarea(app.SzczegoloweDanePanel);
            app.ZawartoscPlikuTextArea.Editable = 'off';
            app.ZawartoscPlikuTextArea.Enable = 'off';
            app.EdytujDame = uibutton(app.SzczegoloweDanePanel, 'push');
            app.EdytujDame.ButtonPushedFcn = createCallbackFcn(app, @EdytujDamePushed, true);
            app.EdytujDame.Text = 'Edytuj';
            app.ZapiszDane = uibutton(app.SzczegoloweDanePanel, 'push');
            app.ZapiszDane.ButtonPushedFcn = createCallbackFcn(app, @ZapiszDanePushed, true);
            app.ZapiszDane.Text = 'Zapisz';
            app.ZawartoscPlikuLabel = uilabel(app.SzczegoloweDanePanel);
            app.ZawartoscPlikuLabel.Enable = 'off';
            app.ZawartoscPlikuLabel.Text = 'Zawartość pliku:';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = szczegoly

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end