classdef szklarnia < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        OpisRoslinyLabel              matlab.ui.control.Label
        OpisRoslinyTextArea           matlab.ui.control.TextArea
        PodlejWszystkieRoslinyButton  matlab.ui.control.Button
        PorarokuEditField             matlab.ui.control.EditField
        PorarokuEditFieldLabel        matlab.ui.control.Label
        NazwaszklarniEditField        matlab.ui.control.EditField
        NazwaszklarniEditFieldLabel   matlab.ui.control.Label
        DataigodzinaEditField         matlab.ui.control.EditField
        DataigodzinaEditField_2Label  matlab.ui.control.Label
        TabGroup                      matlab.ui.container.TabGroup
        RolinyTab                     matlab.ui.container.Tab
        StanRosliny14Lamp             matlab.ui.control.Lamp
        StanRosliny13Lamp             matlab.ui.control.Lamp
        StanRosliny12Lamp             matlab.ui.control.Lamp
        StanRosliny11Lamp             matlab.ui.control.Lamp
        StanRosliny10Lamp             matlab.ui.control.Lamp
        StanRosliny9Lamp              matlab.ui.control.Lamp
        StanRosliny8Lamp              matlab.ui.control.Lamp
        StanRosliny7Lamp              matlab.ui.control.Lamp
        StanRosliny6Lamp              matlab.ui.control.Lamp
        StanRosliny5Lamp              matlab.ui.control.Lamp
        StanRosliny4Lamp              matlab.ui.control.Lamp
        StanRosliny3Lamp              matlab.ui.control.Lamp
        StanRosliny2Lamp              matlab.ui.control.Lamp
        RoslinyTable                  matlab.ui.control.Table
        StanRosliny1Lamp              matlab.ui.control.Lamp
        PomocButton                   matlab.ui.control.Button
        SzczegolyButton               matlab.ui.control.Button
        OdswiezButton                 matlab.ui.control.Button
        ModelszklarniPanel            matlab.ui.container.Panel
        TemperaturaWewnatrzEditField  matlab.ui.control.NumericEditField
        TemperaturawewnatrzEditFieldLabel  matlab.ui.control.Label
        TemperaturaZewnatrzJednostkaLabel  matlab.ui.control.Label
        TemperaturaWewnatrzJednostkaLabel  matlab.ui.control.Label
        TemperaturaZewnatrzEditField  matlab.ui.control.NumericEditField
        TemperaturanazewntrzEditFieldLabel  matlab.ui.control.Label
        Image                         matlab.ui.control.Image
    end

    
    properties (Access = private)
        Property % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'System zarządzania szklarnią';            

            % Wybranie właściwej nazwy szklarni z pliku
            if (exist('szklarnia.txt','file') == 2)
            fileID = fopen('szklarnia.txt');
            dane = fscanf(fileID, '%s');
            params = strsplit(dane,',');
            fclose(fileID);
            app.NazwaszklarniEditField.Value = string(params(1));
            else
            app.NazwaszklarniEditField.Value = "Moja szklarnia";
            end

            % Wybranie aktualnej daty i godziny
            date = datestr(datetime('now'), 'dd/mm/yyyy HH:MM');
            app.DataigodzinaEditField.Value = date;

            % Wybranie właściwej pory roku
            mo = month(datetime('now'));
            if(ismember(mo, [12,1,2]))
                app.PorarokuEditField.Value = "Zima";
            elseif(ismember(mo,[3,4,5]))
                app.PorarokuEditField.Value = "Wiosna";
            elseif(ismember(mo,[6,7,8]))
                app.PorarokuEditField.Value = "Lato";
            elseif(ismember(mo,[9,10,11]))
                app.PorarokuEditField.Value = "Jesień";
            end

            % Wybieranie parametrów rośliny i wyświetlanie ich w tabeli
            app.RoslinyTable.ColumnName = {'Nazwa', 'Pochłanianie', 'Wysokość', 'Stan', 'Data następnego podlania'};
            app.RoslinyTable.Data = {'Pomidory', '4.3', '45', 'usycha', '17/01/2023'};
            app.RoslinyTable.Data = vertcat(app.RoslinyTable.Data, {'Tulipan', '0.3', '10', 'umiarkowane', '18/01/2023'});
            app.RoslinyTable.Data = vertcat(app.RoslinyTable.Data, {'Truskawki', '2.1', '15', 'nawodnione', '19/01/2023'});
            app.RoslinyTable.Data = vertcat(app.RoslinyTable.Data, {'Maliny', '1.8', '12', 'nawodnione', '19/01/2023'});
            app.RoslinyTable.Data = vertcat(app.RoslinyTable.Data, {'Róże', '0.5', '23', 'nawodnione', '20/01/2023'});

            aktualizujDiody();
            %data = app.RoslinyTable.Data;
            %zmienKolorLampy(app.StanRosliny1Lamp,data(1,4));
            % aktualizujDiody();
            %cos1 = strjoin(data(1,4));
            %cos2 = strjoin(data(2,4));
            %cos3 = strjoin(data(3,4));
            %app.RoslinyTable.Data = vertcat(app.RoslinyTable.Data, {cos1, cos2, cos3, 'lol', '1/20/2023'});
            

    % ====================== FUNKCJE =====================

    % aktualizuje kolor jednej diody
    function zmienKolorLampy(app, color)
        switch strjoin(color)
            case 'usycha'
                app.Color = [1 0 0];
            case 'nawodnione'
                app.Color = [0 1 0];
            case 'umiarkowane'
                app.Color = [1 1 0];
            otherwise
                app.Color = [0 0 0];
                error('Invalid color parameter');
        end
    end

    % aktualizuje kolor wszystkich diód
            function aktualizujDiody()
                data = app.RoslinyTable.Data;
                iloscRoslin = height(data);
                %for r = 1:iloscRoslin
                %    if (any(cellfun(@ischar,data(r,4)))) zmienKolorLampy(app.StanRoslinyLamp,data(r,4)); end
                %end
                if (any(cellfun(@ischar,data(1,4)))) zmienKolorLampy(app.StanRosliny1Lamp,data(1,4)); end
                if (any(cellfun(@ischar,data(2,4)))) zmienKolorLampy(app.StanRosliny2Lamp,data(2,4)); end
                if (any(cellfun(@ischar,data(3,4)))) zmienKolorLampy(app.StanRosliny3Lamp,data(3,4)); end
                if (any(cellfun(@ischar,data(4,4)))) zmienKolorLampy(app.StanRosliny4Lamp,data(4,4)); end
                if (any(cellfun(@ischar,data(5,4)))) zmienKolorLampy(app.StanRosliny5Lamp,data(5,4)); end
                if (any(cellfun(@ischar,data(6,4)))) zmienKolorLampy(app.StanRosliny6Lamp,data(6,4)); end
                if (any(cellfun(@ischar,data(7,4)))) zmienKolorLampy(app.StanRosliny7Lamp,data(7,4)); end
                if (any(cellfun(@ischar,data(8,4)))) zmienKolorLampy(app.StanRosliny8Lamp,data(8,4)); end
                if (any(cellfun(@ischar,data(9,4)))) zmienKolorLampy(app.StanRosliny9Lamp,data(9,4)); end
                if (any(cellfun(@ischar,data(10,4)))) zmienKolorLampy(app.StanRosliny10Lamp,data(10,4)); end
                if (any(cellfun(@ischar,data(11,4)))) zmienKolorLampy(app.StanRosliny11Lamp,data(11,4)); end
                if (any(cellfun(@ischar,data(12,4)))) zmienKolorLampy(app.StanRosliny12Lamp,data(12,4)); end
                if (any(cellfun(@ischar,data(13,4)))) zmienKolorLampy(app.StanRosliny13Lamp,data(13,4)); end
            end

    % odczytuje dane z pliku tekstowego i zapisuje do tablicy dwuwymiarowej
    function dataArray = wczytajDane(fileName)
        data = importdata(fileName);
        records = strsplit(data, ';');
        dataArray = {};
        for i = 1:length(records)
            fields = strsplit(records{i}, ',');
            dataArray(i,:) = fields;
        end
    end


        end

        % Button pushed function: SzczegolyButton
        function SzczegolyButtonPushed(app, event)
            szczegoly;
        end

        % Button pushed function: PomocButton
        function PomocButtonPushed(app, event)
            pomoc;
        end

        % Button pushed function: PodlejWszystkieRoslinyButton
        function PodlejWszystkieRoslinyButtonPushed(app, event)
            table = app.RoslinyTable;
            data = get(table,'Data');
            rowscount = height(data);
            colscount = 5;
            for row = 1:rowscount
                for column = 1:colscount
                    if column == 4
                        data(row, column) = {'nawodnione'};
                    elseif column == 5
                        data(row, column) = {datestr(datetime("today")+days(4), 'dd/mm/yyyy')};
                    else
                        data(row, column) = data(row, column);
                    end
                end
            end
            table.Data = data;

            app.StanRosliny1Lamp.Color = [0 1 0];
            app.StanRosliny2Lamp.Color = [0 1 0];
            app.StanRosliny3Lamp.Color = [0 1 0];
            app.StanRosliny4Lamp.Color = [0 1 0];
            app.StanRosliny5Lamp.Color = [0 1 0];
        end

        % Cell selection callback: RoslinyTable
        function RoslinyTableCellSelection(app, event)
            % pobiera indeks zaznaczonej komórki
            selectedData = event.Indices; 
            % ustawia zawartość komórki jako wartość w TextArea
            app.OpisRoslinyTextArea.Value = app.RoslinyTable.Data(selectedData(1), selectedData(2)); 

            %data = app.RoslinyTable.Data;
            %app.OpisRoslinyTextArea.Value = trjoin(data(1,4));
        end

        % Button pushed function: OdswiezButton
        function OdswiezButtonPushed(app, event)

            startupFcn(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ModelszklarniPanel = uipanel(app.UIFigure);
            app.ModelszklarniPanel.Title = 'Model szklarni';
            app.Image = uiimage(app.ModelszklarniPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Dane', 'szklarnia zdjęcie.jpg');
            app.TemperaturanazewntrzEditFieldLabel = uilabel(app.ModelszklarniPanel);
            app.TemperaturanazewntrzEditFieldLabel.Text = 'Temperatura na zewnątrz';
            app.TemperaturaZewnatrzEditField = uieditfield(app.ModelszklarniPanel, 'numeric');
            app.TemperaturaZewnatrzEditField.Value = 12;
            app.TemperaturaWewnatrzJednostkaLabel = uilabel(app.ModelszklarniPanel);
            app.TemperaturaWewnatrzJednostkaLabel.Text = '°C';
            app.TemperaturaZewnatrzJednostkaLabel = uilabel(app.ModelszklarniPanel);
            app.TemperaturaZewnatrzJednostkaLabel.Text = '°C';
            app.TemperaturawewnatrzEditFieldLabel = uilabel(app.ModelszklarniPanel);
            app.TemperaturawewnatrzEditFieldLabel.Text = 'Temperatura wewnatrz';
            app.TemperaturaWewnatrzEditField = uieditfield(app.ModelszklarniPanel, 'numeric');
            app.TemperaturaWewnatrzEditField.Value = 20;
            app.OdswiezButton = uibutton(app.UIFigure, 'push');
            app.OdswiezButton.ButtonPushedFcn = createCallbackFcn(app, @OdswiezButtonPushed, true);
            app.OdswiezButton.Tooltip = {'Umożliwia wyeksportowanie danych rośliny do pliku zewnętrznego'};
            app.OdswiezButton.Text = 'Odśwież';
            app.SzczegolyButton = uibutton(app.UIFigure, 'push');
            app.SzczegolyButton.ButtonPushedFcn = createCallbackFcn(app, @SzczegolyButtonPushed, true);
            app.SzczegolyButton.Tooltip = {'Umożliwia edytowanie danych dotyczących szklarni'};
            app.SzczegolyButton.Text = 'Szczegóły';
            app.PomocButton = uibutton(app.UIFigure, 'push');
            app.PomocButton.ButtonPushedFcn = createCallbackFcn(app, @PomocButtonPushed, true);
            app.PomocButton.Tooltip = {'Otwiera okienko Pomocy'};
            app.PomocButton.Text = 'Pomoc';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.RolinyTab = uitab(app.TabGroup);
            app.RolinyTab.Title = 'Rośliny';
            app.StanRosliny1Lamp = uilamp(app.RolinyTab);
            app.RoslinyTable = uitable(app.RolinyTab);
            app.RoslinyTable.ColumnName = '';
            app.RoslinyTable.RowName = {};
            app.RoslinyTable.CellSelectionCallback = createCallbackFcn(app, @RoslinyTableCellSelection, true);
            app.StanRosliny2Lamp = uilamp(app.RolinyTab);
            app.StanRosliny3Lamp = uilamp(app.RolinyTab);
            app.StanRosliny4Lamp = uilamp(app.RolinyTab);
            app.StanRosliny5Lamp = uilamp(app.RolinyTab);
            app.StanRosliny6Lamp = uilamp(app.RolinyTab);
            app.StanRosliny7Lamp = uilamp(app.RolinyTab);
            app.StanRosliny8Lamp = uilamp(app.RolinyTab);
            app.StanRosliny9Lamp = uilamp(app.RolinyTab);
            app.StanRosliny10Lamp = uilamp(app.RolinyTab);
            app.StanRosliny11Lamp = uilamp(app.RolinyTab);
            app.StanRosliny12Lamp = uilamp(app.RolinyTab);
            app.StanRosliny13Lamp = uilamp(app.RolinyTab);
            app.StanRosliny14Lamp = uilamp(app.RolinyTab);
            app.DataigodzinaEditField_2Label = uilabel(app.UIFigure);
            app.DataigodzinaEditField_2Label.Text = 'Data i godzina';
            app.DataigodzinaEditField = uieditfield(app.UIFigure, 'text');
            app.DataigodzinaEditField.Editable = 'off';
            app.NazwaszklarniEditFieldLabel = uilabel(app.UIFigure);
            app.NazwaszklarniEditFieldLabel.Text = 'Nazwa szklarni';
            app.NazwaszklarniEditField = uieditfield(app.UIFigure, 'text');
            app.NazwaszklarniEditField.Editable = 'off';
            app.PorarokuEditFieldLabel = uilabel(app.UIFigure);
            app.PorarokuEditFieldLabel.Text = 'Pora roku';
            app.PorarokuEditField = uieditfield(app.UIFigure, 'text');
            app.PorarokuEditField.Editable = 'off';
            app.PodlejWszystkieRoslinyButton = uibutton(app.UIFigure, 'push');
            app.PodlejWszystkieRoslinyButton.ButtonPushedFcn = createCallbackFcn(app, @PodlejWszystkieRoslinyButtonPushed, true);
            app.PodlejWszystkieRoslinyButton.Text = 'Podlej wszystkie rośliny';
            app.OpisRoslinyTextArea = uitextarea(app.UIFigure);
            app.OpisRoslinyTextArea.Editable = 'off';
            app.OpisRoslinyLabel = uilabel(app.UIFigure);
            app.OpisRoslinyLabel.Text = 'Opis rośliny';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = szklarnia

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end